classdef AcquireAndStoreImagesForDeepLearning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        SaveDirectoryEditFieldLabel   matlab.ui.control.Label
        SaveDirectoryEditField        matlab.ui.control.EditField
        SaveandacquireButton          matlab.ui.control.Button
        AcquireButton                 matlab.ui.control.Button
        XSpinnerLabel                 matlab.ui.control.Label
        XSpinner                      matlab.ui.control.Spinner
        YSpinnerLabel                 matlab.ui.control.Label
        YSpinner                      matlab.ui.control.Spinner
        StartingIndexEditFieldLabel   matlab.ui.control.Label
        StartingIndexEditField        matlab.ui.control.NumericEditField
        ClassifierEditFieldLabel      matlab.ui.control.Label
        ClassifierEditField           matlab.ui.control.EditField
        ClassifierDirectoryEditFieldLabel  matlab.ui.control.Label
        ClassifierDirectoryEditField  matlab.ui.control.EditField
        savecsvButton                 matlab.ui.control.Button
        CurrentIndexEditFieldLabel    matlab.ui.control.Label
        CurrentIndexEditField         matlab.ui.control.NumericEditField
        NextFieldLampLabel            matlab.ui.control.Label
        NextFieldLamp                 matlab.ui.control.Lamp
        ValidateButton                matlab.ui.control.Button
        ClassifiedasTextAreaLabel     matlab.ui.control.Label
        ClassifiedasTextArea          matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        MyRGBProcessing 
        MyCamera
        MyRGB_Image     % full RGB image
        MySubImg        % Sub image
        Name_Descriptor_list
        MyNet           % trained deep learning network
        Property6 % Description
    end
    
    methods (Access = private)
        
        function results = acquire(app)
            app.MyRGB_Image = app.MyCamera.getRGBframe(10);
            imshow(app.MyRGB_Image, 'Parent', app.UIAxes);
            
            % extract the sub image
            app.MySubImg = app.MyRGBProcessing.ExtractSubimage(app.MyRGB_Image, app.XSpinner.Value, app.YSpinner.Value);
            imshow(app.MySubImg, 'Parent', app.UIAxes2);
  
        end
        
        function results = SaveImage(app)
            Myfilename = app.SaveDirectoryEditField.Value + "image" + num2str(app.CurrentIndexEditField.Value) + ".png";
            i = app.CurrentIndexEditField.Value;
            if (numel(app.Name_Descriptor_list) > 0)
                app.Name_Descriptor_list(i,:) = { convertStringsToChars("image" + num2str(app.CurrentIndexEditField.Value) + ".png") , convertStringsToChars(convertCharsToStrings(app.ClassifierEditField.Value))};
            else
                app.Name_Descriptor_list = { convertStringsToChars("image" + num2str(app.CurrentIndexEditField.Value) + ".png") , convertStringsToChars(convertCharsToStrings(app.ClassifierEditField.Value))};
            end
            imwrite(app.MySubImg,Myfilename);
        end
        
        
        function results = ValidateNetwork(app)
            app.MyRGB_Image = app.MyCamera.getRGBframe(10);
            imshow(app.MyRGB_Image, 'Parent', app.UIAxes);
            
            % extract the sub image
            app.MySubImg = app.MyRGBProcessing.ExtractSubimage(app.MyRGB_Image, app.XSpinner.Value, app.YSpinner.Value);
            
            [BW,maskedRGBImage] = ColorSaturationFilter4(app.MySubImg);
            se = offsetstrel('ball',3,3);
            %se = strel('cube',3)
            J = imerode(maskedRGBImage,se);
            mask = find(J==0);
            maskedRGBImage(mask) = 0;
            
            maskedRGBImage(1:35,:,:) = 128;
            maskedRGBImage(end-35:end,:,:) = 128;
            maskedRGBImage(:,1:35,:) = 128;
            maskedRGBImage(:,end-35:end,:) = 128;
            
            imshow(maskedRGBImage, 'Parent', app.UIAxes2);


            [YPred,scores] = classify(app.MyNet, maskedRGBImage)
            msg = string(YPred) + ' ' + num2str(max(scores))*100 + '%';
            app.ClassifiedasTextArea.Value = msg
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MyRGBProcessing = RGBProcessing();
            app.MyCamera = RealSensePointcloud(); 
            load('ChessGoogleDeepLearningNet2.mat');
            app.MyNet = net;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            app.MyCamera.Disconnect();
        end

        % Button pushed function: AcquireButton
        function AcquireButtonPushed(app, event)
            app.acquire();
        end

        % Button pushed function: SaveandacquireButton
        function SaveandacquireButtonPushed(app, event)
            app.SaveImage();
            app.CurrentIndexEditField.Value = app.CurrentIndexEditField.Value + 1;
            app.acquire();
            if (mod(app.CurrentIndexEditField.Value,10) == 0)
                app.NextFieldLamp.Color = [0.00,1.00,0.00];
            else
                app.NextFieldLamp.Color = [0.20,0.20,0.20];
            end
        end

        % Button pushed function: savecsvButton
        function savecsvButtonPushed(app, event)
            
            % write the matrix to excell sheet
            Myfilename = app.ClassifierDirectoryEditField.Value + "DescrptorLabel_" + app.ClassifierEditField.Value + ".csv";
            myData = app.Name_Descriptor_list;
            writecell(myData,Myfilename);
         
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            app.ValidateNetwork();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Webcam image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Extracted Image for saving')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.SaveDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.SaveDirectoryEditFieldLabel.Text = 'Save Directory:';
            app.SaveDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.SaveDirectoryEditField.Value = 'G:\ChessDeepLearning\WhitePawn\';
            app.SaveandacquireButton = uibutton(app.UIFigure, 'push');
            app.SaveandacquireButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandacquireButtonPushed, true);
            app.SaveandacquireButton.Text = 'Save and acquire';
            app.AcquireButton = uibutton(app.UIFigure, 'push');
            app.AcquireButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireButtonPushed, true);
            app.AcquireButton.Text = 'Acquire';
            app.XSpinnerLabel = uilabel(app.UIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.UIFigure);
            app.XSpinner.Limits = [1 8];
            app.XSpinner.Value = 1;
            app.YSpinnerLabel = uilabel(app.UIFigure);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.UIFigure);
            app.YSpinner.Limits = [1 8];
            app.YSpinner.Value = 1;
            app.StartingIndexEditFieldLabel = uilabel(app.UIFigure);
            app.StartingIndexEditFieldLabel.Text = 'StartingIndex';
            app.StartingIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingIndexEditField.Limits = [1 Inf];
            app.StartingIndexEditField.Value = 8140;
            app.ClassifierEditFieldLabel = uilabel(app.UIFigure);
            app.ClassifierEditFieldLabel.Text = 'Classifier';
            app.ClassifierEditField = uieditfield(app.UIFigure, 'text');
            app.ClassifierEditField.Value = 'white Pawn';
            app.ClassifierDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.ClassifierDirectoryEditFieldLabel.Text = 'Classifier Directory:';
            app.ClassifierDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.ClassifierDirectoryEditField.Value = 'G:\ChessDeepLearning\';
            app.savecsvButton = uibutton(app.UIFigure, 'push');
            app.savecsvButton.ButtonPushedFcn = createCallbackFcn(app, @savecsvButtonPushed, true);
            app.savecsvButton.Text = 'save csv';
            app.CurrentIndexEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentIndexEditFieldLabel.Text = 'Current Index';
            app.CurrentIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentIndexEditField.Limits = [1 Inf];
            app.CurrentIndexEditField.Value = 8140;
            app.NextFieldLampLabel = uilabel(app.UIFigure);
            app.NextFieldLampLabel.Text = 'Next Field';
            app.NextFieldLamp = uilamp(app.UIFigure);
            app.ValidateButton = uibutton(app.UIFigure, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.ClassifiedasTextAreaLabel = uilabel(app.UIFigure);
            app.ClassifiedasTextAreaLabel.Text = 'Classified as:';
            app.ClassifiedasTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AcquireAndStoreImagesForDeepLearning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end