classdef ChessRobot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        YourMoveTextAreaLabel  matlab.ui.control.Label
        YourMoveTextArea       matlab.ui.control.TextArea
        ExecuteMoveButton      matlab.ui.control.Button
        ReconsiderButton       matlab.ui.control.Button
        ReinitBoardButton      matlab.ui.control.Button
        RobotKnobLabel         matlab.ui.control.Label
        RobotKnob              matlab.ui.control.DiscreteKnob
        DetectPiecesButton     matlab.ui.control.Button
        InitializeRGBButton    matlab.ui.control.Button
        NewReferenceButton     matlab.ui.control.Button
        CameraSwitchLabel      matlab.ui.control.Label
        CameraSwitch           matlab.ui.control.ToggleSwitch
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
        Tab2                   matlab.ui.container.Tab
        scrapAxis              matlab.ui.control.UIAxes
        DebugButton            matlab.ui.control.Button
        CalculatemoveButton    matlab.ui.control.Button
        FenStringTextArea      matlab.ui.control.TextArea
        FenStringListBox       matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        MyChessBoard
        MyCBDisp
        MyRobot
        MyRGBProcessing
        MyCamera
        MyStockfish
    end
    
    methods (Access = private)
        
        function results = LocalizePieces(app)
            
            % RGB processing debug ToDo REMOVE
            %             load('ptCloud_full_board.mat');
            %             app.MyRGBProcessing.UpdateBeforeMovePicture(MyRGB_Image);
            %
            %MyCamera = RealSensePointcloud();
            
            
            % Take image, name "before_move"
            [X,Y,Z] = app.MyCamera.getPointcloud(10, 0);
            MyRGB_Image = app.MyCamera.getRGBframe(30);
            
            %             % Take pawn and second picture "after_move"
            %             [X,Y,Z] = MyCamera.getPointcloud(10, 0);
            %             MyRGB_Image = MyCamera.getRGBframe(10);
            %
            %             % remove all peieces and take picture "RGB_calibration"
            %             [X,Y,Z] = MyCamera.getPointcloud(10, 0);
            %             MyRGB_Image = MyCamera.getRGBframe(10);
            %
            %             % set pawn to center and take picture "PC_calibration"
            %             [X,Y,Z] = MyCamera.getPointcloud(10, 0);
            %             MyRGB_Image = MyCamera.getRGBframe(10);
            
            
            KeepMyRGB_Image = MyRGB_Image;
            
            %load('ptCloud_full_board.mat');
            %load('ptCloud_before_move.mat');
            %load('ptCloud_after_move.mat');


            % ------------------------------------------------------- %
            
            
            MyPointCloudProc = PointCloudProcessing(X, Y, Z);
            
            % calibrate the depth camera
            % for alibration place a pawn in the middle of the empty board
            % stop the code here and  save the new calibration data 
            % save('ptCloud_Depth_camera_calibration.mat','MyRGB_Image','X','Y','Z');
            %load('ptCloud_Depth_camera_calibration_backup.mat');
            calibration = load("D:\MatLab Projects\Data\Calibration.mat");
            MyPointCloudProc.CalibratePointCloudProcessing(calibration.point_cloud.Location(:,1),calibration.point_cloud.Location(:,2),calibration.point_cloud.Location(:,3));
            
            % clip the ground plane to leave only data at the height of the
            % board pieces. This requires the calibration step completed.
            MyPointCloudProc.getValidIndex();
            
            MyPointCloudProc.DisplayRawPointData(1); % this is debug
            MyPointCloudProc.DisplayPointCloud(2); % this is debug
            
            % create an initial clustering
            MyPointCloudProc.getClusters();
            MyPointCloudProc.colorClusters(2);
            
            % condition the cluster with 10 iterations
            MyPointCloudProc.ClusterConditioning(10);
            % MyPointCloudProc.colorClusters(3);      % Debug
            
            % calculate the center of mass of the resulting clusters
            MyPointCloudProc.calcClusterCenter();
            %MyPointCloudProc.PrintClusterCenter(1,5); % Debug
            MyPointCloudProc.PrintClusterCenter(2,5); % Debug
            
            
            % perform the RGB camera processing
            
            %app.MyRGBProcessing.AnalyzeFieldOccupation(KeepMyRGB_Image);
            
%             app.MyRGBProcessing.UpdatePostMovePicture(KeepMyRGB_Image);
%             app.MyRGBProcessing.ProcessImages();
            %             app.MyRGBProcessing.getMax();
            %             RGB_changes = [ app.MyRGBProcessing.max1Index, app.MyRGBProcessing.max2Index];


            %app.MyChessBoard.CompareDetectorWithBoard(MyPointCloudProc.corrected_coors, MyPointCloudProc.numClusters,app.MyRGBProcessing.WhitePiecesOnBoard);
            app.MyChessBoard.CompareDetectorWithBoard(MyPointCloudProc.corrected_coors, MyPointCloudProc.numClusters,0);
            app.MyChessBoard.AnalyzePositionChanges();
            app.MyCBDisp.RefreshBoard();
            app.MyChessBoard.DisplayBoard();
            
            %Update the Chess engine
%             startPos = ;
%             endPos = ;
%             app.MyStockfish.bestmove.move =
%             app.MyStockfish.stockfishParser.ConvertToString(startPos, endPos);
%             app.MyStockfish.UpdateMove('w');


            % update the reference for detecting the next move
            MyRGB_Image = app.MyCamera.getRGBframe(30);
            app.MyRGBProcessing.UpdateBeforeMovePicture(MyRGB_Image);
            
            
            % ------------------------------------------------------- %
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MyCBDisp = DiplayChessboard(app.UIAxes);
            app.MyCBDisp.RefreshBoard();
            
            app.MyChessBoard = ChessBoard(app.MyCBDisp);
            app.MyChessBoard.Initialize();
            app.MyChessBoard.DisplayBoard();
            
            %Initialize Stockfish
            % ------------------------------------------------------- %
            app.MyStockfish = Stockfish(app.MyChessBoard);
            % ------------------------------------------------------- %
            app.MyStockfish.fen
            
            
            % Mario: DEBUG REMOVe thi is to be replaced by a callibration procedure!
            Camera_board_offset = [0.0, 0.0, 0.2];
            app.MyChessBoard.SendPointCloudOffset(Camera_board_offset);
            
            app.MyRobot = ArmKinematics();
            app.MyRobot.StartUpRobot();
            %app.MyRobot.SuspendRobot();
            %app.RobotKnobValueChanged.value = "Suspend";
        end

        % Value changed function: RobotKnob
        function RobotKnobValueChanged(app, event)
            value = app.RobotKnob.Value;
            if strcmp(value, 'Suspend')
                app.MyRobot.SetNeutralPose();
                app.MyRobot.SuspendRobot();
            end
            if strcmp(value, 'Off')
                app.MyRobot.PowerDownRobot();
            end
            if strcmp(value, 'On')
                app.MyRobot.StrobeLEDs();
                app.MyRobot.WakeUpFromSuspend();
                app.MyRobot.SetNeutralPose();
            end
            if strcmp(value, 'Overwatch')
                app.MyRobot.SetOverwatch();
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MyRobot.PowerDownRobot();
            app.MyRobot.Close();
            
            delete(app)
            
        end

        % Button pushed function: DetectPiecesButton
        function DetectPiecesButtonPushed(app, event)
            app.LocalizePieces();
        end

        % Button pushed function: InitializeRGBButton
        function InitializeRGBButtonPushed(app, event)
            app.MyRGBProcessing = RGBProcessing(app.scrapAxis);
            load('ptCloud_full_board.mat');
            app.MyRGBProcessing.UpdateBeforeMovePicture(MyRGB_Image);
        end

        % Button pushed function: NewReferenceButton
        function NewReferenceButtonPushed(app, event)
            MyRGB_Image = app.MyCamera.getRGBframe(30);
            app.MyRGBProcessing.UpdateBeforeMovePicture(MyRGB_Image);            
        end

        % Button pushed function: ReinitBoardButton
        function ReinitBoardButtonPushed(app, event)
            %load('ptCloud_full_board.mat');
            %app.MyRGBProcessing.UpdateBeforeMovePicture(Image);
            app.MyChessBoard.Initialize();
            app.MyChessBoard.DisplayBoard();
        end

        % Value changed function: CameraSwitch
        function CameraSwitchValueChanged(app, event)
            value = app.CameraSwitch.Value;
            if strcmp(value,'Off')
                app.MyCamera.Disconnect();
            end
            if strcmp(value,'On')
                app.MyCamera = RealSensePointcloud();
            end
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            app.MyCBDisp.AnimateMove();
        end

        % Button pushed function: CalculatemoveButton
        function CalculatemoveButtonPushed(app, event)
            %Update board to current state in case it is not
            app.MyStockfish.UpdatePosition('b');
            
            %Get best move based on current state
            app.MyStockfish.GetBestMove();
            
            %debug
            [startPos, endPos] = app.MyStockfish.stockfishParser.ConvertToNumber(app.MyStockfish.bestmove.move);
            startPos
            endPos
            
            % execute the move with the robot arm
            
             %ToDO the logic to determine the optimal pick-up andgle is missing as well as the lookup which type of figure is played
            [MyPiece, MyColor] = app.MyChessBoard.LookUpPiece(startPos(1), startPos(2));
            
            MyGrabberAngle = app.MyChessBoard.SuggestPickUpAngle(startPos(1), startPos(2));

            error = app.MyRobot.MoveChesspieceOnBoard(MyPiece, startPos(1), startPos(2), endPos(1), endPos(2), MyGrabberAngle);
            
            app.MyRobot.SetOverwatch();
            
            % update our chess class to the new position
            app.MyChessBoard.ExecuteMove(startPos(1), startPos(2), endPos(1), endPos(2));
            app.MyChessBoard.DisplayBoard();
            app.MyCBDisp.RefreshBoard();
            app.MyChessBoard.DisplayBoard();
            
            %Play the move in stockfish
            app.MyStockfish.UpdateMove('b');
                 
                 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.YourMoveTextAreaLabel = uilabel(app.UIFigure);
            app.YourMoveTextAreaLabel.Text = 'YourMove:';
            app.YourMoveTextArea = uitextarea(app.UIFigure);
            app.ExecuteMoveButton = uibutton(app.UIFigure, 'push');
            app.ExecuteMoveButton.Text = 'Execute Move';
            app.ReconsiderButton = uibutton(app.UIFigure, 'push');
            app.ReconsiderButton.Text = 'Reconsider';
            app.ReinitBoardButton = uibutton(app.UIFigure, 'push');
            app.ReinitBoardButton.ButtonPushedFcn = createCallbackFcn(app, @ReinitBoardButtonPushed, true);
            app.ReinitBoardButton.Text = 'Reinit Board';
            app.RobotKnobLabel = uilabel(app.UIFigure);
            app.RobotKnobLabel.Text = 'Robot';
            app.RobotKnob = uiknob(app.UIFigure, 'discrete');
            app.RobotKnob.Items = {'Off', 'Suspend', 'On', 'Overwatch'};
            app.RobotKnob.ValueChangedFcn = createCallbackFcn(app, @RobotKnobValueChanged, true);
            app.DetectPiecesButton = uibutton(app.UIFigure, 'push');
            app.DetectPiecesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectPiecesButtonPushed, true);
            app.DetectPiecesButton.Text = 'Detect Pieces';
            app.InitializeRGBButton = uibutton(app.UIFigure, 'push');
            app.InitializeRGBButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeRGBButtonPushed, true);
            app.InitializeRGBButton.Text = 'Initialize RGB';
            app.NewReferenceButton = uibutton(app.UIFigure, 'push');
            app.NewReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @NewReferenceButtonPushed, true);
            app.NewReferenceButton.Text = 'New Reference';
            app.CameraSwitchLabel = uilabel(app.UIFigure);
            app.CameraSwitchLabel.Text = 'Camera';
            app.CameraSwitch = uiswitch(app.UIFigure, 'toggle');
            app.CameraSwitch.ValueChangedFcn = createCallbackFcn(app, @CameraSwitchValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.scrapAxis = uiaxes(app.Tab2);
            title(app.scrapAxis, 'Title')
            xlabel(app.scrapAxis, 'X')
            ylabel(app.scrapAxis, 'Y')
            app.DebugButton = uibutton(app.UIFigure, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.CalculatemoveButton = uibutton(app.UIFigure, 'push');
            app.CalculatemoveButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatemoveButtonPushed, true);
            app.CalculatemoveButton.Text = 'Calculate move';
            app.FenStringTextArea = uitextarea(app.UIFigure);
            app.FenStringListBox = uilistbox(app.UIFigure);
            app.FenStringListBox.Items = {'Easy', 'Medium', 'Hard'};
            app.FenStringListBox.Value = 'Easy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChessRobot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end